classdef panels_ROI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExperimentdirectoryLabel  matlab.ui.control.Label
        expDirEditField           matlab.ui.control.EditField
        imgTypeSwitch             matlab.ui.control.Switch
        loadRefImgButton          matlab.ui.control.Button
        TrialnumberLabel          matlab.ui.control.Label
        trialNumSpinner           matlab.ui.control.Spinner
        MaxintensityLabel         matlab.ui.control.Label
        maxIntensityEditField     matlab.ui.control.EditField
        PlanesLabel               matlab.ui.control.Label
        planesListBox             matlab.ui.control.ListBox
        updateDisplayButton       matlab.ui.control.Button
        drawButton                matlab.ui.control.Button
        onLabel                   matlab.ui.control.Label
        planeSelectDropdown       matlab.ui.control.DropDown
        andaddittoLabel           matlab.ui.control.Label
        selectedROIEditField      matlab.ui.control.EditField
        createROIButton           matlab.ui.control.Button
        deleteROIButton           matlab.ui.control.Button
        saveROIsButton            matlab.ui.control.Button
        plotPanel                 matlab.ui.container.Panel
        BrowseButton              matlab.ui.control.Button
        ROIsListBox               matlab.ui.control.ListBox
        msgLabel                  matlab.ui.control.Label
        selectAllROIsButton       matlab.ui.control.Button
        selectNoROIsButton        matlab.ui.control.Button
        selectAllPlanesButton     matlab.ui.control.Button
        loadROIsButton            matlab.ui.control.Button
        upOnePlaneButton          matlab.ui.control.Button
        downOnePlaneButton        matlab.ui.control.Button
        RogueLabel                matlab.ui.control.Label
        maxZButton                matlab.ui.control.Button
        shiftROIUpButton          matlab.ui.control.Button
        shiftROIDownButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        defaultExpDir = 'D:\Dropbox (HMS)\2P Data\Imaging Data';    % Starting directory for browse button
        expDir                                                      % The directory that the current refImages were loaded from
        refImages = [];                                             % Reference images for all trials
        trialNums = [];                                             % Vector of trial numbers for the loaded refImages
        refImageAxes = [];                                          % Array of axes handles for each plane in the current trial
        axMaxZ = [];                                                % Handle of the axes for plotting max Z projections
        currTrialInd = [];                                          % Index of the currently selected trial num
        cm = repmat([ rgb('Blue'); rgb('Green'); rgb('Red'); ...    % List of colors to cycle through for parent ROIs
            rgb('Cyan'); rgb('Magenta'); rgb('Gold'); ...
            rgb('Brown'); rgb('Teal') ; rgb('Purple') ; ...
            rgb('Orange'); rgb('Indigo'); rgb('SpringGreen')], 50, 1);
        currColorInd = 1;                                           % Keeps track of which color is up next
        roiData = [];                                               % Holds information about the current list of ROIs
        defaultSaveName = 'roiDefs';                                % Default value for the save file name prefix
    end
    
    methods (Access = private)
        
        % ----------------------- Clear everything that would change between trials ----------------------
        function clear_trial_data(app)
            
            app.currTrialInd = [];
            app.planesListBox.Items = {};
            
            clear_plot_data(app)
            
        end
        
        % ------------------------------------ Clear plotting panel -------------------------------------
        function clear_plot_data(app)
            
            % Hide all axes and plots
            if ~isempty(app.refImageAxes)
                for iPlot = 1:numel(app.refImageAxes)
                    app.refImageAxes{iPlot}.Visible = 'off';
                    app.refImageAxes{iPlot}.Title.Visible = 'off';
                    for iChild = 1:numel(app.refImageAxes{iPlot}.Children)
                        app.refImageAxes{iPlot}.Children(iChild).Visible = 'off';
                    end
                end
            end
            app.planeSelectDropdown.Items = {};
            if ~isempty(app.axMaxZ)
                app.axMaxZ.Visible = 'off';
                app.axMaxZ.Title.Visible = 'off';
                for iChild = 1:numel(app.axMaxZ.Children)
                    app.axMaxZ.Children(iChild).Visible = 'off';
                end
            end
        end
        
        % ------------------------------- Update the reference image plots -------------------------------
        function update_display(app)
            
            % Clear plotting panel
            clear_plot_data(app)
            
            % Get the numbers of the currently selected planes
            planeNums = strip(app.planesListBox.Value);
            
            % Calculate the necessary subplot dimensions
            nPlanes = numel(planeNums);
            subplotDim1 = ceil(sqrt(nPlanes));
            subplotDim2 = floor(sqrt(nPlanes));
            if (subplotDim1 * subplotDim2) < nPlanes
                subplotDim2 = ceil(sqrt(nPlanes));
            end
            padSize = 0.01;
            rightMargin = 0;
            axWidth = (1 - rightMargin - ((subplotDim1 + 1) * padSize)) / subplotDim1;
            axHeight = (1 - ((subplotDim2 + 1) * padSize)) / subplotDim2;
            
            % Create axes in correct locations
            roiRefImgAxes = [];
            for yDim = 1:subplotDim2
                for xDim = 1:subplotDim1
                    xPos = (xDim * padSize) + ((xDim-1) * axWidth);
                    yPos = (yDim * padSize) + ((yDim-1) * axHeight);
                    roiRefImgAxes{yDim, xDim} = axes(app.plotPanel, ...
                        'Units', 'Normalized', ...
                        'Position', [xPos, yPos, axWidth, axHeight]);
                end
            end
            
            % Order handles by z-planes, displayed from L-R, top-bottom
            roiRefImgAxes = reshape(flipud(roiRefImgAxes)', 1, numel(roiRefImgAxes));
            
            % Delete any extra (unused) axes
            if numel(roiRefImgAxes) > nPlanes
                for iAx = numel(roiRefImgAxes):-1:(nPlanes + 1)
                    delete(roiRefImgAxes{iAx})
                end
                roiRefImgAxes = roiRefImgAxes(1:nPlanes);
            end
            
            % Plot reference frames for each plane
            for iPlane = 1:nPlanes
                currAxes = roiRefImgAxes{iPlane};
                axes(currAxes)
                axis(currAxes, 'image')
                hold(currAxes, 'on');
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                imshow(app.refImages{app.currTrialInd}(:, :, str2double(planeNums{iPlane})), [0 maxIntensity], ...
                    'InitialMagnification', 'fit', 'Parent', currAxes);
                currAxes.Title.String = ['Plane #' planeNums{iPlane}];
                currAxes.UserData = planeNums{iPlane};
                currAxes.Toolbar.Visible = 'off';
                currAxes.Interactions = [];
                axis(currAxes, 'image');
            end
            
            % Check to see if any existing ROIs need to be transferred to the new axes
            for iROI = 1:numel(app.ROIsListBox.Items)
                currROIName = app.ROIsListBox.Items{iROI};
                currROIData = app.roiData(iROI);
                
                % Loop through each subROI
                for iSubROI = 1:numel(currROIData.subROIs)
                    currPlane = currROIData.subROIs(iSubROI).plane;
                    [~, currPlaneInd] = ismember(num2str(currPlane), planeNums);
                    
                    if currPlaneInd
                        
                        % Transfer subROI over to the new parent axes
                        currROIData.subROIs(iSubROI).obj.Parent = roiRefImgAxes{currPlaneInd};
                        
                        % Adjust visibility of subROIs according to ROIsListBox selection status
                        if ismember(currROIName, app.ROIsListBox.Value)
                            currROIData.subROIs(iSubROI).obj.Visible = 'on';
                        else
                            currROIData.subROIs(iSubROI).obj.Visible = 'off';
                        end
                        
                    end
                end%iSubROI
            end%iROI
            
            % Update stored axes handles
            oldAxes = app.refImageAxes(str2double(planeNums));
            app.refImageAxes(str2double(planeNums)) = roiRefImgAxes;
            for iAx = 1:numel(oldAxes)
                delete(oldAxes{iAx})
            end
            
            % (Re)populate the plane select dropdown
            for iPlane = 1:nPlanes
                app.planeSelectDropdown.Items{iPlane} = ['Plane ', planeNums{iPlane}];
            end
            if numel(app.planeSelectDropdown.Items) > 0
                app.planeSelectDropdown.Value = app.planeSelectDropdown.Items{1};
            end
            
        end%function
        
        % ------------------------------- Shift display up or down one plane -------------------------------
        function shift_display(app, direction)
            
            if strcmp(direction, 'up')
                
                % Shift all selected planes down by one plane number index (corresponds to a shift up in Z)
                % unless the dorsalmost plane is already selected.
                currPlanes = app.planesListBox.Value;
                currPlaneInds = find(ismember(app.planesListBox.Items, currPlanes));
                if min(currPlaneInds) > 1
                    currPlaneInds = currPlaneInds - 1;
                    app.planesListBox.Value = app.planesListBox.Items(currPlaneInds);
                    update_display(app);
                end
                
            elseif strcmp(direction, 'down')
                
                % Shift all selected planes up by one plane number index (corresponds to a shift down in Z)
                % unless the ventralmost plane is already selected.
                currPlanes = app.planesListBox.Value;
                currPlaneInds = find(ismember(app.planesListBox.Items, currPlanes));
                if max(currPlaneInds) < numel(app.planesListBox.Items)
                    currPlaneInds = currPlaneInds + 1;
                    app.planesListBox.Value = app.planesListBox.Items(currPlaneInds);
                    update_display(app);
                end
            end
            
            
        end
    end%methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Prompt user for experiment directory
            expDir = uigetdir(app.defaultExpDir, 'Select a directory containing reference image files');
            if ~expDir
                expDir = '';
            end
            app.expDirEditField.Value = expDir; %#ok<*ADPROPLC>
            %             app.expDirEditField.Value = 'D:\Dropbox (HMS)\2P Data\Imaging Data\20191113-1_38A11-ChR-6m\ProcessedData';
            
        end

        % Button pushed function: loadRefImgButton
        function loadRefImgButtonPushed(app, event)
            
            % Determine whether to look for raw or registered files
            imgType = app.imgTypeSwitch.Value;
            if contains(imgType, 'Raw')
                imgType = 'raw';
            else
                imgType = 'reg';
            end
            
            % Attempt to find reference image files in the experiment directory
            app.expDir = app.expDirEditField.Value;
            %refImgFiles = dir(fullfile(app.expDir, ['refImages_', imgType, '_trial*.mat']));
            trial_folders = dir(app.expDir);
            refImgFiles = {};
            for trial_folder = 1:length(trial_folders)
                if isfolder(fullfile(trial_folders(trial_folder).folder,trial_folders(trial_folder).name))
                    fileNames = dir(fullfile(trial_folders(trial_folder).folder,trial_folders(trial_folder).name));
                    for file = 1:length(fileNames)
                        if contains(fileNames(file).name,'reg')
                            refImgFiles{trial_folder,file} = [fullfile(fileNames(file).folder,fileNames(file).name)];
                        end
                    end
                end
            end
            
            refImgFiles = refImgFiles(~cellfun('isempty',refImgFiles));
            if numel(refImgFiles) == 0
                uialert(app.UIFigure, 'Error: could not find any reference image files of that type in the selected directory')
            else
                
                % Clear any existing data
                app.trialNums = [];
                app.refImages = [];
                clear_trial_data(app);
                
                % Extract trial numbers and compile images from each file
                for iFile = 1:numel(refImgFiles)
                    %load(refImgFiles(iFile).name), 'refImages') % refImages: [y, x, plane]
                    load(refImgFiles{iFile}) % refImages: [y, x, plane]                    
                    %trialNum = str2double(regexp(refImgFiles(iFile).name, '(?<=trial_)...(?=.mat)', 'match', 'once'));
                    sessionNum = str2num(strtok([refImgFiles{iFile}(regexp(refImgFiles{iFile}, 'sid_.*_refImages')+4),refImgFiles{iFile}(regexp(refImgFiles{iFile}, 'sid_.*_refImages')+5)],'_'));
                    app.trialNums(iFile) = sessionNum;
                    app.refImages{iFile} = refImages;
                end
                app.msgLabel.Text = [' Loaded ', num2str(numel(refImgFiles)), ' reference image files'];
                
                % Create axes for displaying maxZ projections if they do not already exist
                if isempty(app.axMaxZ)
                    app.axMaxZ = axes(app.plotPanel, ...
                        'Units', 'Normalized', ...
                        'Position', [0.01, 0.01, 0.98, 0.98]);
                end
                app.axMaxZ.Visible = 'off';
            end
            
            % Set trial number to first trial and then trigger the spinner's callback
            app.trialNumSpinner.Value = app.trialNums(1);
            trialNumSpinnerValueChanged(app);
            
        end

        % Value changed function: trialNumSpinner
        function trialNumSpinnerValueChanged(app, event)
            
            app.msgLabel.Text = ' Loading new trial...';
            
            % Clear data from the previously selected trial (unless ref images were just loaded)
            if ~isempty(app.planesListBox.Items)
                oldSelectedPlanes = app.planesListBox.Value;
                clear_trial_data(app)
            end
            
            % Determine whether new value is one of the loaded data's trial numbers
            if sum(app.trialNums == app.trialNumSpinner.Value)
                
                % Get index of the current trial
                app.currTrialInd = find(app.trialNums == app.trialNumSpinner.Value);
                
                % Count number of planes for current trial and add them to planes list
                nPlanes = size(app.refImages{app.currTrialInd}, 3);
                
                % Populate the plane select listBox
                for iPlane = 1:nPlanes
                    app.planesListBox.Items{iPlane} = pad(num2str(iPlane), 5, 'left');
                end
                try
                    % Attempt to re-select the same planes from before
                    app.planesListBox.Value = oldSelectedPlanes;
                catch
                    % Select all planes by default if the list has changed
                    app.planesListBox.Value = app.planesListBox.Items;
                end
                % Create temporary placeholder axes if necessary
                nAxes = numel(app.refImageAxes);
                if nAxes < nPlanes
                    for iAx = (nAxes + 1):nPlanes
                        app.refImageAxes{iAx} = axes(app.plotPanel, 'Visible', 'off');
                    end
                end
                
                % Update the display
                update_display(app);
                
                app.msgLabel.Text = [' Trial ', num2str(app.trialNumSpinner.Value), ' loaded'];
            else
                % Leave everything blank if there's no data for that trial
                app.msgLabel.Text = ' Warning: no reference image was found for this trial number';
            end
            
        end

        % Button pushed function: createROIButton
        function createROIButtonPushed(app, event)
            
            % Ask user to enter a name for the ROI
            opts.Interpreter = 'tex';
            roiName = inputdlg('\fontsize{12}Please enter a name for the new ROI:', '', 1 , {''}, opts);
            
            % Make sure they entered a valid name
            currROIs = app.ROIsListBox.Items;
            if isempty(roiName)
                app.msgLabel.Text = ' ROI creation cancelled';
            elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                uialert(app.UIFigure, ...
                    "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
            else
                % Add a new entry to the ROIs listbox and select it
                app.roiData(end + 1).name = roiName{:};
                app.roiData(end).subROIs = struct('obj', [], 'plane', [], 'refImage', []);
                app.roiData(end).color = app.cm(app.currColorInd, :);
                app.currColorInd = app.currColorInd + 1;
                app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                app.ROIsListBox.Value = app.roiData(end).name;
                ROIsListBoxValueChanged(app);
            end
            
            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            
            if numel(selectedItems) > 1 || isempty(selectedItems)
                app.selectedROIEditField.Value = '[select an ROI from the list]';
            else
                selectedItems = selectedItems{:};
                app.selectedROIEditField.Value = selectedItems;
            end
            
        end

        % Button pushed function: deleteROIButton
        function deleteROIButtonPushed(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            if ~isempty(selectedItems)
                selection = uiconfirm(app.UIFigure, 'Are you sure you want to delete the selected ROIs?', ...
                    'Confirm deletion');
                if strcmpi(selection, 'OK')
                    
                    % Make a list of the names of ROIs to be deleted
                    deleteROINames = {};
                    for iROI = 1:numel(selectedItems)
                        deleteROINames{iROI} = selectedItems{iROI};
                    end
                    
                    % Delete the ROIs in the listBox items and in the roiData property
                    delItems = ismember(app.ROIsListBox.Items, deleteROINames);
                    app.ROIsListBox.Items(delItems) = [];
                    app.roiData(delItems) = [];
                    app.msgLabel.Text = [' Deleted ', num2str(numel(delItems)), ' ROIs'];
                    
                    % Reset the selected ROI edit field
                    app.selectedROIEditField.Value = '[select an ROI from the list]';
                    
                    % Update the display in the plot panel
                    update_display(app)
                else
                    app.msgLabel.Text = ' ROI deletion cancelled';
                end
            end
            
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            
            % Check plane and ROI list selection status
            targetPlaneStr = app.planeSelectDropdown.Value;
            targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
            
            % Get parent ROI info
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            if isnumeric(str2double(targetPlane)) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
                
                % Grab handle to the appropriate axes
                for iPlot = 1:numel(app.refImageAxes)
                    if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                        targetAxes = app.refImageAxes{iPlot};
                    end
                end
                
                % Prompt user to draw the new ROI
                titleStr = targetAxes.Title.String;
                targetAxes.Title.String = '*** DRAW AN ROI ***';
                newROI = images.roi.Freehand(targetAxes, 'Color', parentROI.color, 'FaceAlpha', 0.05);
                newROI.draw();
                newROI.Label = parentROI.name;
                targetAxes.Title.String = titleStr;
                
                % Save information about new ROI
                if isempty(app.roiData(parentROIIndex).subROIs(1).obj)
                    app.roiData(parentROIIndex).subROIs(1).obj = newROI;
                else
                    app.roiData(parentROIIndex).subROIs(end + 1).obj = newROI;
                end
                app.roiData(parentROIIndex).subROIs(end).plane = str2double(targetPlane);
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: updateDisplayButton
        function updateDisplayButtonPushed(app, event)
            app.msgLabel.Text = ' Updating plots...';
            update_display(app)
            app.msgLabel.Text = ' Plots updated';
        end

        % Button pushed function: saveROIsButton
        function saveROIsButtonPushed(app, event)
            
            
            % ----------- CHECK FOR ERRORS ------------
            proceed = 1;
            
            % Make sure there is at least one selected ROI in the list
            if isempty(app.ROIsListBox.Value)
                proceed = 0;
                uialert(app.UIFigure, 'Error: no ROIs are selected in the ROI list!', 'Error');
                app.msgLabel.Text = ' Saving cancelled';
            else
                
                % Make sure each selected ROI has at least one subROI
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    if isempty(currROIData.subROIs(1).obj)
                        proceed = 0;
                        uialert(app.UIFigure, ['Error: parent ROI "', currROIData.name, ...
                            '" does not have any subROIs so it cannot be saved!'], 'Error');
                        app.msgLabel.Text = ' Saving cancelled';
                        break
                    else
                        
                        % Make sure no sub-ROI has a plane number greater than the current trial's nPlanes
                        for iSubROI = 1:numel(currROIData.subROIs)
                            if currROIData.subROIs(iSubROI).plane > numel(app.planesListBox.Items)
                                proceed = 0;
                                uialert(app.UIFigure, ['Error: at least one sub-ROI of parent ROI "', currROIData.name, ...
                                    '" has a plane number greater than the number of planes in reference images for the ', ...
                                    'current trial!'], 'Error');
                                app.msgLabel.Text = ' Saving cancelled';
                                break
                            end
                        end%iSubROI
                        if ~proceed
                            break % Exit the outer loop as well if the inner one found an error
                        end
                    end%if
                end%iROI
                
                % Warn user if there are unselected ROIs in the listBox
                if numel(app.ROIsListBox.Value) ~= numel(app.ROIsListBox.Items)
                    selection = uiconfirm(app.UIFigure, ['There is at least one unselected ROI in the list that will not be saved', ...
                        '...are you sure you want to proceed?'], 'Confirm save');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
                
                % Warn user if there are any sub-ROIs from selected ROIs that are not currently shown on the plots
                warnUser = 0;
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    for iSubROI = 1:numel(currROIData.subROIs)
                        if strcmpi(currROIData.subROIs(iSubROI).obj.Visible, 'off')
                            warnUser = 1;
                            break
                        end
                    end%iSubROI
                end%iROI
                if warnUser
                    selection = uiconfirm(app.UIFigure, ['Warning: one or more sub-ROIs are not currently displayed in the ' ...
                        'reference image plots, but they will still be included in the saved ', ...
                        'ROI data if you continue. Are you sure you want to proceed?'], 'Confirm save');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
            end%if
            
            % Prompt user for a file name
            if proceed
                opts.Interpreter = 'tex';
                fileNamePrefix = inputdlg('\fontsize{12}Please enter a prefix for the save file name:', '', 1 , {app.defaultSaveName}, opts);
                app.defaultSaveName = fileNamePrefix{:}; % Save whatever the user entered to be the new default
                
                % Warn user if saving will overwrite an existing file
                saveFile = fullfile(app.expDir, [fileNamePrefix{:}, '_trial_', pad(num2str(app.trialNumSpinner.Value), 3, 'left', '0'), '.mat']);
                if exist(saveFile, 'file')
                    selection = uiconfirm(app.UIFigure, ['There is an existing file with that name that will be overwritten by this ', ...
                        'save...are you sure you want to proceed?'], 'Confirm file overwrite');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
            end
            
            
            % ----------- SAVE ROI DATA ------------
            
            if proceed
                
                % Process data for each ROI
                selectedROIData = app.roiData(ismember(app.ROIsListBox.Value, {app.roiData.name}));
                for iROI = 1:numel(selectedROIData)
                    for iSubROI = 1:numel(selectedROIData(iROI).subROIs)
                        
                        currSubROIObj = selectedROIData(iROI).subROIs(iSubROI).obj;
                        
                        % Link a reference image to each sub-ROI
                        planeNum = selectedROIData(iROI).subROIs(iSubROI).plane;
                        selectedROIData(iROI).subROIs(iSubROI).refImage = app.refImages{app.currTrialInd}(:, :, planeNum);
                        
                        % Extract properties from ROI object
                        selectedROIData(iROI).subROIs(iSubROI).position = currSubROIObj.Position;
                        selectedROIData(iROI).subROIs(iSubROI).mask = currSubROIObj.createMask();
                        
                    end%iSubROI
                end%iROI
                
                % Save ROI data
                roiDefs = selectedROIData;
                save(saveFile, 'roiDefs', '-v7.3');
                app.msgLabel.Text = [' ROIs saved for trial #', num2str(app.trialNumSpinner.Value)];
            end
            
            
        end

        % Button pushed function: selectAllROIsButton
        function selectAllROIsButtonPushed(app, event)
            app.ROIsListBox.Value = app.ROIsListBox.Items;
        end

        % Button pushed function: selectNoROIsButton
        function selectNoROIsButtonPushed(app, event)
            app.ROIsListBox.Value = {};
        end

        % Button pushed function: selectAllPlanesButton
        function selectAllPlanesButtonPushed(app, event)
            app.planesListBox.Value = app.planesListBox.Items;
            %               test = app.roiData
        end

        % Button pushed function: loadROIsButton
        function loadROIsButtonPushed(app, event)
            
            % Ask user to select an ROI def file
            proceed = 1;
            [file, path] = uigetfile(fullfile(app.expDir, '*.mat'), 'Select an ROI def file');
            if ischar(file) && ischar(path)
                load(fullfile(path, file), 'roiDefs');
            else
                proceed = 0;
            end
            
            % Check if import will overwrite existing ROIs
            if proceed
                for iROI = 1:numel(roiDefs)
                    if ismember(roiDefs(iROI).name, app.ROIsListBox.Items)
                        selection = uiconfirm(app.UIFigure, ['Warning: the import you have requested will overwrite one or ', ...
                            'more existing ROIs! Do you want to continue?'], 'Confirm ROI overwrite');
                        if ~strcmpi(selection, 'OK')
                            proceed = 0;
                        end
                        break % Apply user's first selection to all imports
                    end
                end
            end
            
            % Import ROIs
            app.msgLabel.Text = ' Importing ROIs...';
            if proceed
                for iROI = 1:numel(roiDefs)
                    
                    % Delete any existing ROI with the same name as the current import
                    delItem = ismember(roiDefs(iROI).name, app.ROIsListBox.Items);
                    if delItem
                        app.ROIsListBox.Items(delItem) = [];
                        app.roiData(delItem) = [];
                    end
                    
                    % Create a new ROI with the appropriate name
                    app.roiData(end + 1).name = roiDefs(iROI).name;
                    app.roiData(end).subROIs = roiDefs(iROI).subROIs;
                    app.roiData(end).color = app.cm(app.currColorInd, :); % Change the color to work with any existing ROIs
                    app.currColorInd = app.currColorInd + 1;
                    app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                    app.ROIsListBox.Value{end + 1} = app.roiData(end).name; % So it's not deleted when the display updates
                    
                    % Transfer the subROI objects over to the appropriate axes
                    for iSubROI = 1:numel(app.roiData(end).subROIs)
                        targetAxes = app.refImageAxes{app.roiData(end).subROIs(iSubROI).plane};
                        app.roiData(end).subROIs(iSubROI).obj.Color = app.roiData(end).color;
                        app.roiData(end).subROIs(iSubROI).obj.UIContextMenu = [];
                        app.roiData(end).subROIs(iSubROI).obj.Parent = targetAxes;
                    end
                    
                    % Update display to plot the new ROIs
                    update_display(app)
                end
                app.msgLabel.Text = [' Imported ', num2str(numel(roiDefs)), ' ROIs'];
            else
                app.msgLabel.Text = ' ROI import cancelled';
            end
            
        end

        % Button pushed function: upOnePlaneButton
        function upOnePlaneButtonPushed(app, event)
            shift_display(app, 'up');
        end

        % Button pushed function: downOnePlaneButton
        function downOnePlaneButtonPushed(app, event)
            shift_display(app, 'down');
        end

        % Button pushed function: maxZButton
        function maxZButtonPushed(app, event)
            
            if ~isempty(app.axMaxZ)
                
                % Hide existing plots
                clear_plot_data(app);
                
                % Get max Z projection for selected planes
                currPlaneNums = str2double(strip(app.planesListBox.Value));
                currPlanesMaxZ = max(app.refImages{app.trialNumSpinner.Value}(:, :, currPlaneNums), [], 3); % --> [y, x];
                %                 currPlanesMaxZ = median(app.refImages{app.trialNumSpinner.Value}(:, :, currPlaneNums), 3); % --> [y, x];
                
                % Show max Z plot
                app.axMaxZ.Visible = 'on';
                axes(app.axMaxZ)
                axis(app.axMaxZ, 'image')
                %                 hold(app.axMaxZ, 'on');
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                imshow(currPlanesMaxZ, [0 maxIntensity], 'InitialMagnification', 'fit', 'Parent', app.axMaxZ);
                app.axMaxZ.Title.String = 'Max Z projection';
                app.axMaxZ.Toolbar.Visible = 'off';
                app.axMaxZ.Interactions = [];
                axis(app.axMaxZ, 'image');
                
                
            end
        end

        % Button pushed function: shiftROIUpButton
        function shiftROIUpButtonPushed(app, event)
            
            % Shift all SubROIs of the selected ROI up one plane (as long as there are none on the first plane)
            if numel(app.ROIsListBox.Value) == 1
                currROIName = app.ROIsListBox.Value{:};
                currROIIdx = strcmp(currROIName, {app.roiData.name});
                subROIPlanes = [app.roiData(currROIIdx).subROIs.plane];
                if min(subROIPlanes) > 1
                    for iSubROI = 1:numel(app.roiData(currROIIdx).subROIs)
                        newPlane = app.roiData(currROIIdx).subROIs(iSubROI).plane - 1;
                        app.roiData(currROIIdx).subROIs(iSubROI).plane = newPlane;
                        app.roiData(currROIIdx).subROIs(iSubROI).refImage = app.refImages{newPlane};
                        app.roiData(currROIIdx).subROIs(iSubROI).obj.Parent = app.refImageAxes{newPlane};
                    end
                end
            end
            update_display(app)
        end

        % Button pushed function: shiftROIDownButton
        function shiftROIDownButtonPushed(app, event)
            
            % Shift all SubROIs of the selected ROI down one plane (as long as there are none on the last plane)
            if numel(app.ROIsListBox.Value) == 1
                currROIName = app.ROIsListBox.Value{:};
                currROIIdx = strcmp(currROIName, {app.roiData.name});
                subROIPlanes = [app.roiData(currROIIdx).subROIs.plane];
                if max(subROIPlanes) < numel(app.refImages)
                    for iSubROI = 1:numel(app.roiData(currROIIdx).subROIs)
                        newPlane = app.roiData(currROIIdx).subROIs(iSubROI).plane + 1;
                        app.roiData(currROIIdx).subROIs(iSubROI).plane = newPlane;
                        app.roiData(currROIIdx).subROIs(iSubROI).refImage = app.refImages{newPlane};
                        app.roiData(currROIIdx).subROIs(iSubROI).obj.Parent = app.refImageAxes{newPlane};
                    end
                end
            end
            update_display(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROI creation GUI';
            app.ExperimentdirectoryLabel = uilabel(app.UIFigure);
            app.ExperimentdirectoryLabel.Text = 'Experiment directory';
            app.expDirEditField = uieditfield(app.UIFigure, 'text');
            app.expDirEditField.Editable = 'off';
            app.imgTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.imgTypeSwitch.Items = {'Raw images', 'Registered'};
            app.imgTypeSwitch.Value = 'Registered';
            app.loadRefImgButton = uibutton(app.UIFigure, 'push');
            app.loadRefImgButton.ButtonPushedFcn = createCallbackFcn(app, @loadRefImgButtonPushed, true);
            app.loadRefImgButton.Text = 'Load reference images';
            app.TrialnumberLabel = uilabel(app.UIFigure);
            app.TrialnumberLabel.Text = 'Trial number';
            app.trialNumSpinner = uispinner(app.UIFigure);
            app.trialNumSpinner.Limits = [0 Inf];
            app.trialNumSpinner.RoundFractionalValues = 'on';
            app.trialNumSpinner.ValueChangedFcn = createCallbackFcn(app, @trialNumSpinnerValueChanged, true);
            app.trialNumSpinner.Value = 1;
            app.MaxintensityLabel = uilabel(app.UIFigure);
            app.MaxintensityLabel.Text = 'Max intensity';
            app.maxIntensityEditField = uieditfield(app.UIFigure, 'text');
            app.maxIntensityEditField.Value = '700';
            app.PlanesLabel = uilabel(app.UIFigure);
            app.PlanesLabel.Text = 'Planes';
            app.planesListBox = uilistbox(app.UIFigure);
            app.planesListBox.Items = {};
            app.planesListBox.Multiselect = 'on';
            app.planesListBox.Value = {};
            app.updateDisplayButton = uibutton(app.UIFigure, 'push');
            app.updateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @updateDisplayButtonPushed, true);
            app.updateDisplayButton.Text = 'Update plots';
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'Draw a new sub-ROI';
            app.onLabel = uilabel(app.UIFigure);
            app.onLabel.Text = 'on';
            app.planeSelectDropdown = uidropdown(app.UIFigure);
            app.planeSelectDropdown.Items = {'Plane'};
            app.planeSelectDropdown.Value = 'Plane';
            app.andaddittoLabel = uilabel(app.UIFigure);
            app.andaddittoLabel.Text = 'and add it to';
            app.selectedROIEditField = uieditfield(app.UIFigure, 'text');
            app.selectedROIEditField.Value = '[select an ROI from the list]';
            app.createROIButton = uibutton(app.UIFigure, 'push');
            app.createROIButton.ButtonPushedFcn = createCallbackFcn(app, @createROIButtonPushed, true);
            app.createROIButton.Text = 'Create new ROI';
            app.deleteROIButton = uibutton(app.UIFigure, 'push');
            app.deleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROIButtonPushed, true);
            app.deleteROIButton.Text = 'Delete selected ROIs';
            app.saveROIsButton = uibutton(app.UIFigure, 'push');
            app.saveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @saveROIsButtonPushed, true);
            app.saveROIsButton.Text = {'Save selected ROIs'; ' for current trial'};
            app.plotPanel = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ROIsListBox = uilistbox(app.UIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.msgLabel = uilabel(app.UIFigure);
            app.msgLabel.Text = '  ';
            app.selectAllROIsButton = uibutton(app.UIFigure, 'push');
            app.selectAllROIsButton.ButtonPushedFcn = createCallbackFcn(app, @selectAllROIsButtonPushed, true);
            app.selectAllROIsButton.Text = 'Select all';
            app.selectNoROIsButton = uibutton(app.UIFigure, 'push');
            app.selectNoROIsButton.ButtonPushedFcn = createCallbackFcn(app, @selectNoROIsButtonPushed, true);
            app.selectNoROIsButton.Text = 'Select none';
            app.selectAllPlanesButton = uibutton(app.UIFigure, 'push');
            app.selectAllPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @selectAllPlanesButtonPushed, true);
            app.selectAllPlanesButton.Text = 'Select all';
            app.loadROIsButton = uibutton(app.UIFigure, 'push');
            app.loadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @loadROIsButtonPushed, true);
            app.loadROIsButton.Text = {'Load ROIs from'; 'external file'};
            app.upOnePlaneButton = uibutton(app.UIFigure, 'push');
            app.upOnePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @upOnePlaneButtonPushed, true);
            app.upOnePlaneButton.Text = 'Shift up';
            app.downOnePlaneButton = uibutton(app.UIFigure, 'push');
            app.downOnePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @downOnePlaneButtonPushed, true);
            app.downOnePlaneButton.Text = 'Shift down';
            app.RogueLabel = uilabel(app.UIFigure);
            app.RogueLabel.Text = 'ROIs';
            app.maxZButton = uibutton(app.UIFigure, 'push');
            app.maxZButton.ButtonPushedFcn = createCallbackFcn(app, @maxZButtonPushed, true);
            app.maxZButton.Text = {'Show max Z for'; 'selected planes'};
            app.shiftROIUpButton = uibutton(app.UIFigure, 'push');
            app.shiftROIUpButton.ButtonPushedFcn = createCallbackFcn(app, @shiftROIUpButtonPushed, true);
            app.shiftROIUpButton.Text = 'Shift ROI up';
            app.shiftROIDownButton = uibutton(app.UIFigure, 'push');
            app.shiftROIDownButton.ButtonPushedFcn = createCallbackFcn(app, @shiftROIDownButtonPushed, true);
            app.shiftROIDownButton.Text = 'Shift ROI Down';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panels_ROI_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end